classdef simView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SimulationSettingsLabel        matlab.ui.control.Label
        tminSlider                     matlab.ui.control.Slider
        tminSliderLabel                matlab.ui.control.Label
        dtSlider                       matlab.ui.control.Slider
        dtSliderLabel                  matlab.ui.control.Label
        NSlider                        matlab.ui.control.Slider
        NSliderLabel                   matlab.ui.control.Label
        AddButton                      matlab.ui.control.Button
        methodListBox                  matlab.ui.control.ListBox
        methodListBoxLabel             matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        PreviewLabel                   matlab.ui.control.Label
        ControlAxes                    matlab.ui.control.UIAxes
        TrajectoryAxes                 matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        SelectedSimulationsLabel       matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        OverviewTab                    matlab.ui.container.Tab
        ResultsPreviewStartTimeSlider  matlab.ui.control.Slider
        ResultsPreviewStartTimeSliderLabel  matlab.ui.control.Label
        BottomPlotSettings             matlab.ui.container.ButtonGroup
        BottomPlotType                 matlab.ui.container.ButtonGroup
        SolverTimeButton               matlab.ui.control.RadioButton
        ControlEffortButton            matlab.ui.control.RadioButton
        InstantButton                  matlab.ui.control.ToggleButton
        AccumulatedButton              matlab.ui.control.ToggleButton
        TrajectoryButtonGroup          matlab.ui.container.ButtonGroup
        ReferenceDropDownLabel_2       matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        DropDown                       matlab.ui.control.DropDown
        RelativetoButton               matlab.ui.control.RadioButton
        AbsoluteButton                 matlab.ui.control.RadioButton
        ECIButton                      matlab.ui.control.ToggleButton
        LVLHButton                     matlab.ui.control.ToggleButton
        ResetButton                    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        previewCfgs = cell(1, 0); % Configs of currently selected simulations
        previewX = cell(1, 0);
        previewS = cell(1, 0);
        previewT = cell(1, 0);
        previewSolverTime = cell(1, 0);
        
    end
    
    methods (Access = private)
        function res = initSlider(app, sld, vals, units)
            sld.Limits = [min(vals), max(vals)];
            sld.MajorTicks = vals;
            sld.MajorTickLabels = sld.MajorTicks + units;
            sld.MinorTicks = [];
            sld.Value = sld.Limits(end);
        end
        
        function res = refreshPlots(app)
            doECI = app.ECIButton.Value;
            doLVLH = app.LVLHButton.Value;
            doAccumulatedCE = app.AccumulatedButton.Value;
            simStartTime = app.ResultsPreviewStartTimeSlider.Value;
            doControlEffort = app.ControlEffortButton.Value;

            if isempty(app.previewCfgs)
                return
            end

            doAbsolute = strcmp(app.ButtonGroup.SelectedObject.Text, 'Absolute');
            doRelative = ~doAbsolute;
            nSims = length(app.previewCfgs);
            refSim = round(str2num(app.DropDown.Value));
            if doRelative && nSims < 2
                return
            end
            
            legendListAbsolute = cell(1, 0);
            legendListRelative = cell(1, 0);
            ignoreSim = zeros(size(app.previewCfgs));
            for ii = 1:length(app.previewCfgs)
                dt = app.previewCfgs{ii}.simulation.dt;
                steps = app.previewCfgs{ii}.simulation.steps;
                if dt*steps < simStartTime
                    ignoreSim(ii) = 1;
                else
                    legendListAbsolute{end+1} = sprintf("%d", ii);
                    if ii ~= refSim
                        legendListRelative{end+1} = sprintf("%d-%d", ii, refSim);
                    end
                end
            end
            
            ta = app.TrajectoryAxes;
            ca = app.ControlAxes;
            cla(ta);
            cla(ca);
            hold(ta, "on");
            hold(ca, "on");
            
            % Plot Trajectories
            if doECI
                axis(ta, "equal");
                title(ta, "Trajectory ECI")
                mindt = 1e10;
                maxT = 0;
                for ii = 1:length(app.previewCfgs)
                    if ignoreSim(ii)
                        continue
                    end
                    cfg = app.previewCfgs{ii};
                    if cfg.simulation.T > maxT
                        maxT = cfg.simulation.T;
                    end
                    if cfg.simulation.dt < mindt
                        mindt = cfg.simulation.dt;
                    end
                end

                if doAbsolute
                    XorbitLVLH = zeros(6, ceil(maxT/mindt));
                    auxCfg = params();
                    auxCfg.simulation.dt = mindt;
                    XorbitECI = lvlh2eci(auxCfg, XorbitLVLH);
                    plot(ta, XorbitECI(1, :), XorbitECI(3, :));
                end

                for ii = 1:length(app.previewCfgs)
                    if ignoreSim(ii)
                        continue
                    end
                    if doRelative
                        if ii == refSim
                            ta.ColorOrderIndex = ta.ColorOrderIndex + 1;
                            continue
                        end
                        Xr = app.previewX{ii};
                        XrECI = lvlh2eci(app.previewCfgs{ii}, Xr);
                        Xref = app.previewX{refSim};
                        XrefECI = lvlh2eci(app.previewCfgs{ii}, Xref);
                        Xx = XrECI(1, app.previewT{ii} > simStartTime);
                        Xxref = XrefECI(1, app.previewT{refSim} > simStartTime);
                        Xz = XrECI(3, app.previewT{ii} > simStartTime);
                        Xzref = XrefECI(3, app.previewT{refSim} > simStartTime);
                        plot(ta, Xx-Xxref ,  Xz-Xzref );
                    else
                        Xr = app.previewX{ii};
                        XrECI = lvlh2eci(app.previewCfgs{ii}, Xr);
                        plot(ta, XrECI(1, app.previewT{ii} > simStartTime), XrECI(3, app.previewT{ii} > simStartTime));
                    end
                end
                
                if doAbsolute
                    legend(ta, "Orbit", legendListAbsolute{:});
                else
                    legend(ta, legendListRelative{:});
                end
            end            
            if doLVLH
                title(ta, "Trajectory LVLH")
                for ii = 1:length(app.previewCfgs)
                    if ignoreSim(ii)
                        continue
                    end
                    if doRelative
                        if ii == refSim
                            ta.ColorOrderIndex = ta.ColorOrderIndex + 1;
                            continue
                        end
                        
                        Xr = app.previewX{ii};
                        Xref = app.previewX{refSim};
                        plot(ta, Xr(1, app.previewT{ii} > simStartTime) - Xref(1, app.previewT{refSim} > simStartTime), Xr(3, app.previewT{ii} > simStartTime) - Xref(3, app.previewT{refSim} > simStartTime));
                    else
                        Xr = app.previewX{ii};
                        plot(ta, Xr(1, app.previewT{ii} > simStartTime), Xr(3, app.previewT{ii} > simStartTime));
                    end
                end
                
                if doAbsolute
                    legend(ta, legendListAbsolute{:});
                else
                    legend(ta, legendListRelative{:});
                end

            end

            % Bottom Plot (Control Effort or Solver Time)
            if doControlEffort
                title(ca, 'Control Effort');
            else
                title(ca, 'Solver Times');
            end
            for ii = 1:length(app.previewCfgs)
                if ignoreSim(ii)
                    continue
                end
                if doControlEffort
                    Sr = app.previewS{ii};
                else
                    Sr = app.previewSolverTime{ii};
                end
                dt = app.previewCfgs{ii}.simulation.dt;
                steps = app.previewCfgs{ii}.simulation.steps;
                t = 0:dt:dt*steps;
                idxs = t > simStartTime;
                t = t(idxs);
                if doControlEffort
                    E = energySpent(Sr, app.previewCfgs{ii});
                else
                    E = Sr;
                end
                E = E(idxs);
                if doAccumulatedCE
                    plot(ca, t, cumsum(E));
                    if doControlEffort
                        ylabel(ca, "Total Time Open (s)");
                    else
                        ylabel(ca, "Total Solver Time (s)");
                    end
                else
                    plot(ca, t, E);
                    if doControlEffort
                        ylabel(ca, "Time Open (s)");
                    else
                        ylabel(ca, "Solver Time (s)");
                    end
                end
            end
            legend(ca, legendListAbsolute{:});
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            N = [5, 10, 20, 50]; % MPC controller horizon
            dt = [5, 10, 20]; % simulation step size
            method = {'standard', 'projected', 'relaxed'}; % MPC solver method
            tmin = [1, 2];

            initSlider(app, app.NSlider, N, "");
            initSlider(app, app.dtSlider, dt, "s");
            app.dtSlider.Value = app.dtSlider.Limits(1);
            initSlider(app, app.tminSlider, tmin, "");
            app.tminSlider.Value = app.tminSlider.Limits(1);

            app.methodListBox.Items = method;
            app.DropDown.Items = {};
            
            title(app.TrajectoryAxes, 'Trajectory LVLH')
            xlabel(app.TrajectoryAxes, 'x (m)')
            ylabel(app.TrajectoryAxes, 'z (m)')

            title(app.ControlAxes, 'Control Effort')
            xlabel(app.ControlAxes, 'Time (s)')
            ylabel(app.ControlAxes, 'Total Time Open (s)')

            app.ResultsPreviewStartTimeSlider.Limits = [0, 1];

        end

        % Value changed function: NSlider, dtSlider, tminSlider
        function discreteSliderValueChanged(app, event)
            sld = event.Source;
            val = sld.Value;
            lst = sld.MajorTicks;
            idxs = find(abs(val-lst) == min(abs(val-lst)));
            sld.Value = sld.MajorTicks(idxs(1));
        end

        % Button pushed function: AddButton
        function AddSimulationPreview(app, event)
            cfg = params();
            selectedN = app.NSlider.Value;
            selectedtmin = app.tminSlider.Value;
            selecteddt = app.dtSlider.Value;
            selectedmethod = app.methodListBox.Value;

            cfg.controller.N = selectedN;
            cfg.controller.tmin = selectedtmin;
            cfg.simulation.dt = selecteddt;
            cfg.simulation.steps = ceil(cfg.simulation.T/cfg.simulation.dt);
            cfg.simulation.method = selectedmethod;

            isRepeatCfg = 0;
            for ii = 1:length(app.previewCfgs)
                if isequal(cfgData(app.previewCfgs{ii}), cfgData(cfg))
                    isRepeatCfg = 1;
                    return;
                end
            end
            newSimName = sprintf("%d", length(app.TabGroup.Children));
            app.DropDown.Items = [app.DropDown.Items(:)', newSimName];

            newSimTab = uitab(app.TabGroup,'Title', newSimName);
            slidersToCopy = ["NSlider", "dtSlider", "tminSlider"];
            sliderTabOffset = [0, -40, 0, 0];
            methodBoxTabOffset = [0, -10, 0, 0];

            for sliderName = slidersToCopy
                labelName = sliderName + "Label";
                sliderLabelCopy = uilabel(newSimTab);
                sliderLabelCopy.HorizontalAlignment = app.(labelName).HorizontalAlignment;
                sliderLabelCopy.Position = app.(labelName).Position + sliderTabOffset;
                sliderLabelCopy.Text = app.(labelName).Text;

                sliderCopy = uislider(newSimTab);
                sliderCopy.Position = app.(sliderName).Position + sliderTabOffset;

                sliderCopy.Limits = app.(sliderName).Limits;
                sliderCopy.MajorTicks = app.(sliderName).MajorTicks;
                sliderCopy.MajorTickLabels = app.(sliderName).MajorTickLabels;
                sliderCopy.MinorTicks = app.(sliderName).MinorTicks;

                sliderCopy.Value = app.(sliderName).Value;
                sliderCopy.Enable = "Off";
            end

            % Copy method list box
            methodListBoxLabelCopy = uilabel(newSimTab);
            methodListBoxLabelCopy.HorizontalAlignment = app.methodListBoxLabel.HorizontalAlignment;
            methodListBoxLabelCopy.Position = app.methodListBoxLabel.Position + methodBoxTabOffset;
            methodListBoxLabelCopy.Text = app.methodListBoxLabel.Text;

            methodListBoxCopy = uilistbox(newSimTab);
            methodListBoxCopy.Items = app.methodListBox.Items;
            methodListBoxCopy.Position = app.methodListBox.Position + methodBoxTabOffset;
            methodListBoxCopy.Value = app.methodListBox.Value;
            methodListBoxCopy.Enable = "Off";

            app.previewCfgs{end+1} = cfg;
            [Xr, Sr, Tr] = simulate(app.previewCfgs{end});
            app.previewX{end+1} = Xr;
            app.previewS{end+1} = Sr;
            app.previewSolverTime{end+1} = Tr;
            app.previewT{end+1} = 0:cfg.simulation.dt:cfg.simulation.dt*cfg.simulation.steps;

            simExtremes = app.ResultsPreviewStartTimeSlider.Limits;
            app.ResultsPreviewStartTimeSlider.Limits = [min([simExtremes(1), 0]), max([simExtremes(2), cfg.simulation.dt*cfg.simulation.steps])];

            refreshPlots(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            while length(app.TabGroup.Children) > 1
                app.TabGroup.Children(end).delete
            end
            app.previewCfgs = cell(1, 0);
            app.previewX = cell(1, 0);
            app.previewS = cell(1, 0);
            app.previewSolverTime = cell(1, 0);
            app.previewT = cell(1, 0);
            ta = app.TrajectoryAxes;
            ca = app.ControlAxes;
            cla(ta);
            cla(ca);
            app.ResultsPreviewStartTimeSlider.Limits = [0, 1];
            app.DropDown.Items = {};
        end

        % Value changed function: DropDown
        function TrajectoryButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrajectoryButtonGroup.SelectedObject;
            refreshPlots(app);
        end

        % Callback function
        function ControlEffortButtonGroupSelectionChanged(app, event)
            selectedButton = app.BottomPlotSettings.SelectedObject;
            refreshPlots(app);
        end

        % Value changed function: ResultsPreviewStartTimeSlider
        function ResultsPreviewStartTimeSliderValueChanged(app, event)
            value = app.ResultsPreviewStartTimeSlider.Value;
            refreshPlots(app);
        end

        % Callback function: BottomPlotSettings, BottomPlotType, 
        % ButtonGroup, TrajectoryButtonGroup
        function updatePlots(app, event)
            refreshPlots(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.methodListBoxLabel = uilabel(app.LeftPanel);
            app.methodListBoxLabel.Text = 'method';
            app.methodListBox = uilistbox(app.LeftPanel);
            app.AddButton = uibutton(app.LeftPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddSimulationPreview, true);
            app.AddButton.Text = 'Add';
            app.NSliderLabel = uilabel(app.LeftPanel);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.LeftPanel);
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @discreteSliderValueChanged, true);
            app.dtSliderLabel = uilabel(app.LeftPanel);
            app.dtSliderLabel.Text = 'dt';
            app.dtSlider = uislider(app.LeftPanel);
            app.dtSlider.ValueChangedFcn = createCallbackFcn(app, @discreteSliderValueChanged, true);
            app.tminSliderLabel = uilabel(app.LeftPanel);
            app.tminSliderLabel.Text = 'tmin';
            app.tminSlider = uislider(app.LeftPanel);
            app.tminSlider.ValueChangedFcn = createCallbackFcn(app, @discreteSliderValueChanged, true);
            app.SimulationSettingsLabel = uilabel(app.LeftPanel);
            app.SimulationSettingsLabel.Text = 'Simulation Settings';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TrajectoryAxes = uiaxes(app.CenterPanel);
            title(app.TrajectoryAxes, 'Title')
            xlabel(app.TrajectoryAxes, 'X')
            ylabel(app.TrajectoryAxes, 'Y')
            zlabel(app.TrajectoryAxes, 'Z')
            app.ControlAxes = uiaxes(app.CenterPanel);
            title(app.ControlAxes, 'Title')
            xlabel(app.ControlAxes, 'X')
            ylabel(app.ControlAxes, 'Y')
            zlabel(app.ControlAxes, 'Z')
            app.PreviewLabel = uilabel(app.CenterPanel);
            app.PreviewLabel.Text = 'Preview';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.ResetButton = uibutton(app.OverviewTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrajectoryButtonGroup = uibuttongroup(app.OverviewTab);
            app.TrajectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @updatePlots, true);
            app.TrajectoryButtonGroup.Title = 'Trajectory';
            app.LVLHButton = uitogglebutton(app.TrajectoryButtonGroup);
            app.LVLHButton.Text = 'LVLH';
            app.LVLHButton.Value = true;
            app.ECIButton = uitogglebutton(app.TrajectoryButtonGroup);
            app.ECIButton.Text = 'ECI';
            app.ButtonGroup = uibuttongroup(app.TrajectoryButtonGroup);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @updatePlots, true);
            app.AbsoluteButton = uiradiobutton(app.ButtonGroup);
            app.AbsoluteButton.Text = 'Absolute';
            app.AbsoluteButton.Value = true;
            app.RelativetoButton = uiradiobutton(app.ButtonGroup);
            app.RelativetoButton.Text = 'Relative to';
            app.DropDown = uidropdown(app.ButtonGroup);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @TrajectoryButtonGroupSelectionChanged, true);
            app.ReferenceDropDownLabel_2 = uilabel(app.TrajectoryButtonGroup);
            app.ReferenceDropDownLabel_2.Text = 'Frame';
            app.BottomPlotSettings = uibuttongroup(app.OverviewTab);
            app.BottomPlotSettings.SelectionChangedFcn = createCallbackFcn(app, @updatePlots, true);
            app.AccumulatedButton = uitogglebutton(app.BottomPlotSettings);
            app.AccumulatedButton.Text = 'Accumulated';
            app.AccumulatedButton.Value = true;
            app.InstantButton = uitogglebutton(app.BottomPlotSettings);
            app.InstantButton.Text = 'Instant';
            app.BottomPlotType = uibuttongroup(app.BottomPlotSettings);
            app.BottomPlotType.SelectionChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.BottomPlotType.ButtonDownFcn = createCallbackFcn(app, @updatePlots, true);
            app.ControlEffortButton = uiradiobutton(app.BottomPlotType);
            app.ControlEffortButton.Text = 'Control Effort';
            app.ControlEffortButton.Value = true;
            app.SolverTimeButton = uiradiobutton(app.BottomPlotType);
            app.SolverTimeButton.Text = 'Solver Time';
            app.ResultsPreviewStartTimeSliderLabel = uilabel(app.OverviewTab);
            app.ResultsPreviewStartTimeSliderLabel.Text = 'Results Preview Start Time';
            app.ResultsPreviewStartTimeSlider = uislider(app.OverviewTab);
            app.ResultsPreviewStartTimeSlider.ValueChangedFcn = createCallbackFcn(app, @ResultsPreviewStartTimeSliderValueChanged, true);
            app.SelectedSimulationsLabel = uilabel(app.RightPanel);
            app.SelectedSimulationsLabel.Text = 'Selected Simulations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end